classdef ContrastInputGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewContrastWizardUIFigure  matlab.ui.Figure
        ContrastDetailsPanel       matlab.ui.container.Panel
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        ContrastEditFieldLabel     matlab.ui.control.Label
        ContrastEditField          matlab.ui.control.EditField
        ErrorMessage               matlab.ui.control.Label
        UpdateContrastButton       matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        DefineNewContrastLabel     matlab.ui.control.Label
        DesignMatrixAxis           matlab.ui.control.UIAxes
        ContrastAxis               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataTable; % table of input data
        origContrastInput;
        contrastOutput; % where we'll store our output to send to calling function (object must inherit from handle        
    end
    
    methods (Access = private)
        
        function [isValidContrast, infoMsg] = validateContrast(app, contrastStr)
            
            isValidContrast = true;
            infoMsg = {};
            
            if isempty(contrastStr)
                isValidContrast = false;
                return;
            end
            
            contrastArray = str2num(contrastStr);
            if (length(contrastStr)>0 && isempty(contrastArray))
                isValidContrast = false;
                infoMsg{end+1} = 'Cannot convert non-numeric characters detected in contrast string';
                return
            end
            
            posContrasts = contrastArray>0;
            negContrasts = contrastArray<0;
            
            if length(contrastArray) ~= size(app.dataTable,2)
                isValidContrast = false;
                infoMsg{end+1} = 'Number of elements in contrast must match underlying data table';
                return;
            end
            
            if ~any(posContrasts)
                isValidContrast = false;
                infoMsg{end+1} = 'Must have at least one positive contrast';
            end
            
            %sum of positive contrasts should be 1
            if sum(contrastArray(posContrasts)) ~= 1
                isValidContrast = false;
                infoMsg{end+1} = 'Positive Contrasts must add to 1';                
            end
            
            %sum of negative contrasts should be -1
            if any(negContrasts) & (sum(contrastArray(negContrasts)) ~= -1)
                isValidContrast = false;
                infoMsg{end+1} = 'Negative Contrasts must add to -1';                
            end
            
            %All contrasts should add to 0???
            if (any(posContrasts) && any(negContrasts)) & (sum(contrastArray) ~= 0)
                isValidContrast = false;
                infoMsg{end+1} = 'If using positive and negative contrasts, all must add to 0';                
            end
        end
        
        function debugContrastInput = loadDebugContrastInput(app)
            testDatTable = readtable(fullfile('/data/wheelock/data1/people/ecka/NLA/HCP_TestData','HCP_flanker_ASR_10subj.csv'));
            
            allDatSubsamp = testDatTable(:,[3,4,5,6,10]);
            
            debugContrastInput = nla.edge.ContrastInput();
            debugContrastInput.dataTable = allDatSubsamp;            
            
        end
        
        function updateDesignMatrixPlot(app)
            
            design_mtx = table2array(app.dataTable);
            design_mtx_norm = (design_mtx - min(design_mtx)) ./ (max(design_mtx) - min(design_mtx));
            
            labels = app.dataTable.Properties.VariableNames;

            ax = app.DesignMatrixAxis;
            [num_scans, num_covariates] = size(design_mtx);
            imagesc(ax, design_mtx_norm');
            set(ax, 'XTick', [], 'XTickLabel', []);
            set(ax, 'YTick', 1:num_covariates, 'YTickLabel', labels);
            set(ax, 'TickDir', 'out');
            colormap(ax, 'gray');
            set(ax, 'TickLabelInterpreter', 'none');
            
        
        end
        
        function updateContrastBarPlot(app)
            numVariables = size(app.dataTable,2);
            barCenters = (1:numVariables) - 0.5;
                        
            if ~isempty(app.contrastOutput) & ~isempty(app.contrastOutput.contrastVector)
                %To match ordering of design matrix, plotting should go top to
                %bottom rather than bottom to top
                flippedContrastVector = flip(app.contrastOutput.contrastVector);
                barh(app.ContrastAxis, barCenters, flippedContrastVector,'FaceColor',[0.5 0.5 0.5]);
            else                
                barh(app.ContrastAxis, barCenters, zeros(numVariables,1));
            end
            set(app.ContrastAxis, 'YTick', 0:numVariables);
            set(app.ContrastAxis, 'YLim', [0 numVariables]);
            set(app.ContrastAxis, 'XLim', [-1 1]);
            drawnow();
        end
        
        function loadEditFieldsFromContrastInput(app, newContrastInput)
            app.NameEditField.Value = newContrastInput.name;
            if ~isempty(newContrastInput.contrastVector)
                contrastStr = num2str(newContrastInput.contrastVector);
                contrastStr = regexprep(contrastStr, '\s+', ' '); %regex to eliminate excessive whitespace between vector elements
                app.ContrastEditField.Value = contrastStr;
            else
                app.ContrastEditField.Value = '';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, contrastInput)
            if nargin == 1
                %use debug value for contrast input
                contrastInput = app.loadDebugContrastInput();
            end
            
            app.loadEditFieldsFromContrastInput(contrastInput);
            
            app.dataTable = contrastInput.dataTable;
            app.origContrastInput = contrastInput.copy(); %make copy of initial contrast used as input so we can revert to original state
            app.contrastOutput = contrastInput; %store handle reference of input, so we can modify fields of this object and changes will be seen in the function that called this GUI         
            app.ErrorMessage.Visible = false;
            
            app.updateDesignMatrixPlot();
            app.updateContrastBarPlot();
            
            figure(app.NewContrastWizardUIFigure);            
            
        end

        % Value changed function: ContrastEditField
        function ContrastEditFieldValueChanged(app, event)
            
                        
            [isValid, errMsg] = app.validateContrast(app.ContrastEditField.Value);
            
            tempContrast = str2num(app.ContrastEditField.Value);
            
            if isValid                
                app.contrastOutput.contrastVector = tempContrast;
                value = app.ContrastEditField.Value;
                app.ContrastEditField.FontColor = [0 0 0];
                app.ErrorMessage.Visible = false;
                app.updateContrastBarPlot();
            else
                app.ContrastEditField.FontColor = [1 0 0];
                app.ErrorMessage.Visible = true;
                errMsgStr = '';
                for i = 1:length(errMsg)
                    errMsgStr = sprintf('%s%s',errMsgStr,errMsg{i});
                    if i<length(errMsg)
                        errMsgStr = sprintf('%s\n',errMsgStr);
                    end
                end
                app.ErrorMessage.Text = errMsgStr;
            end
                
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            isValidContrast = app.validateContrast(app.ContrastEditField.Value);
            if ~isValidContrast
                errordlg('Invalid contrast, correct issues in contrast vector');
                return
            end
            
            isValidName = any(~isspace(app.NameEditField.Value));
            if ~isValidName
                errordlg('Name field cannot be blank')
                return;
            end
            
            if isValidContrast & isValidName
                app.contrastOutput.name = app.NameEditField.Value;
                app.contrastOutput.contrastVector = str2num(app.ContrastEditField.Value);
                close(app.NewContrastWizardUIFigure);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %set fields of contrast to match those of original input
            app.contrastOutput.name = app.origContrastInput.name;
            app.contrastOutput.contrastVector = app.origContrastInput.contrastVector;
            close(app.NewContrastWizardUIFigure);            
        end

        % Close request function: NewContrastWizardUIFigure
        function NewContrastWizardUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewContrastWizardUIFigure = uifigure('Visible', 'off');
            app.NewContrastWizardUIFigure.Name = 'New Contrast Wizard';
            app.NewContrastWizardUIFigure.CloseRequestFcn = createCallbackFcn(app, @NewContrastWizardUIFigureCloseRequest, true);
            app.ContrastDetailsPanel = uipanel(app.NewContrastWizardUIFigure);
            app.ContrastDetailsPanel.Title = 'Contrast Details';
            app.NameEditFieldLabel = uilabel(app.ContrastDetailsPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.ContrastDetailsPanel, 'text');
            app.ContrastEditFieldLabel = uilabel(app.ContrastDetailsPanel);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.ContrastDetailsPanel, 'text');
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.ErrorMessage = uilabel(app.ContrastDetailsPanel);
            app.ErrorMessage.Text = 'Contrast Error Message';
            app.UpdateContrastButton = uibutton(app.ContrastDetailsPanel, 'push');
            app.UpdateContrastButton.Text = 'Update Contrast';
            app.CancelButton = uibutton(app.NewContrastWizardUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.NewContrastWizardUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DefineNewContrastLabel = uilabel(app.NewContrastWizardUIFigure);
            app.DefineNewContrastLabel.Text = 'Define New Contrast...';
            app.DesignMatrixAxis = uiaxes(app.NewContrastWizardUIFigure);
            title(app.DesignMatrixAxis, 'Design Matrix')
            xlabel(app.DesignMatrixAxis, 'subjects')
            app.DesignMatrixAxis.XTick = [];
            app.DesignMatrixAxis.YTick = [];
            app.ContrastAxis = uiaxes(app.NewContrastWizardUIFigure);
            title(app.ContrastAxis, 'Contrast')
            xlabel(app.ContrastAxis, 'value')
            app.ContrastAxis.XTick = [-1 0 1];
            app.ContrastAxis.XTickLabel = {'-1'; '0'; '1'};
            app.ContrastAxis.YTick = [];
            app.ContrastAxis.YTickLabel = '';
            % Show the figure after all components are created
            app.NewContrastWizardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContrastInputGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewContrastWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewContrastWizardUIFigure)
        end
    end
end