classdef SandwichEstimatorInput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SandwichEstimatorInputUIFigure  matlab.ui.Figure
        LoadCovariatesButton            matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        CovariateFileNameLabel          matlab.ui.control.Label
        CovariateSetupPanel             matlab.ui.container.Panel
        AddNumericalButton              matlab.ui.control.Button
        RemoveNumericalButton           matlab.ui.control.Button
        AddCategoricalButton            matlab.ui.control.Button
        RemoveCategoricalButton         matlab.ui.control.Button
        AddGroupButton                  matlab.ui.control.Button
        RemoveGroupButton               matlab.ui.control.Button
        UnselectedCovariatesListBoxLabel  matlab.ui.control.Label
        UnselectedCovariatesListBox     matlab.ui.control.ListBox
        NumericalListBoxLabel           matlab.ui.control.Label
        NumericalListBox                matlab.ui.control.ListBox
        CategoricalListBoxLabel         matlab.ui.control.Label
        CategoricalListBox              matlab.ui.control.ListBox
        GroupListBoxLabel               matlab.ui.control.Label
        GroupListBox                    matlab.ui.control.ListBox
        CovarSelectErrorLabel           matlab.ui.control.Label
        ContrastSetupPanel              matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        StandardErrorPanel              matlab.ui.container.Panel
        StdErrorMethodDropDownLabel     matlab.ui.control.Label
        StdErrorMethodDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        allCovariates
        unselectedCovariates
        numericalCovariates
        categoricalCovariates
        groupVariable
        stdObjType
        contrasts
        
        unselectedIdxs
        numericalIdxs
        categoricalIdxs
        groupIdx
        
    end
    
    methods (Access = private)
        
        function initializeCovariateListBoxes(app)
            app.UnselectedCovariatesListBox.Items = app.allCovariates';
            app.CategoricalListBox.Items = {};
            app.NumericalListBox.Items = {};
            app.GroupListBox.Items = {};
        end
        function resetIdxsToAllUnselected(app)
            app.unselectedIdxs = true(1,length(app.allCovariates));
            app.numericalIdxs = false(1,length(app.allCovariates));
            app.categoricalIdxs = false(1,length(app.allCovariates));
            app.groupIdx = false(1,length(app.allCovariates));            
        end
        
        function logicalMatchVector = getMatchVectorOnCovariateName(app, covarNameStr)
            
            logicalMatchVector = cellfun(@(x) strcmpi(x,covarNameStr), app.allCovariates);
        
        end
        function updateListBoxes(app)
            app.UnselectedCovariatesListBox.Items = app.allCovariates(app.unselectedIdxs);
            app.CategoricalListBox.Items = app.allCovariates(app.categoricalIdxs);
            app.NumericalListBox.Items = app.allCovariates(app.numericalIdxs);
            app.GroupListBox.Items = app.allCovariates(app.groupIdx);
            
            app.CovarSelectErrorLabel.Visible = false;
        end
        
        function getStdErrorOptions(app)
            
        
        end
        
        function updateContrastTable(app)
            initTbl = table(app.allCovariates(app.numericalIdxs)', zeros(sum(app.numericalIdxs),1),'VariableNames',{'Var','Contrast'});
            app.UITable.Data = initTbl;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCovariatesButton
        function LoadCovariatesButtonPushed(app, event)
            [fName, pName] = uigetfile('*', 'Select Data File');
            if isempty(fName)
                return;
            end
            
            inDat = readtable(fullfile(pName,fName));
            app.allCovariates = inDat.Properties.VariableNames;
            
            app.CovariateFileNameLabel.Text = fName;
            app.initializeCovariateListBoxes();
            app.resetIdxsToAllUnselected();
            
        end

        % Button pushed function: AddNumericalButton
        function AddNumericalButtonPushed(app, event)
            varsToAdd = app.UnselectedCovariatesListBox.Value;
            for i = 1:length(varsToAdd)
                currMatchVec = app.getMatchVectorOnCovariateName(varsToAdd{i});
                app.numericalIdxs = app.numericalIdxs | currMatchVec;
            end
            app.unselectedIdxs = app.unselectedIdxs & (~app.numericalIdxs);
            app.updateListBoxes();
            app.updateContrastTable();
        end

        % Button pushed function: RemoveNumericalButton
        function RemoveNumericalButtonPushed(app, event)
            varsToRemove = app.NumericalListBox.Value;
            for i = 1:length(varsToRemove)
                currMatchVec = app.getMatchVectorOnCovariateName(varsToRemove{i});
                app.numericalIdxs = app.numericalIdxs & (~currMatchVec);
                app.unselectedIdxs = app.unselectedIdxs | currMatchVec;
            end
            
            app.updateListBoxes();
            app.updateContrastTable();
        end

        % Button pushed function: AddCategoricalButton
        function AddCategoricalButtonPushed(app, event)
            varsToAdd = app.UnselectedCovariatesListBox.Value;
            for i = 1:length(varsToAdd)
                currMatchVec = app.getMatchVectorOnCovariateName(varsToAdd{i});
                app.categoricalIdxs = app.categoricalIdxs | currMatchVec;
            end
            app.unselectedIdxs = app.unselectedIdxs & (~app.categoricalIdxs);
            app.updateListBoxes();
        end

        % Button pushed function: RemoveCategoricalButton
        function RemoveCategoricalButtonPushed(app, event)
            varsToRemove = app.CategoricalListBox.Value;
            for i = 1:length(varsToRemove)
                currMatchVec = app.getMatchVectorOnCovariateName(varsToRemove{i});
                app.categoricalIdxs = app.categoricalIdxs & (~currMatchVec);
                app.unselectedIdxs = app.unselectedIdxs | currMatchVec;
            end
            
            app.updateListBoxes();
        end

        % Button pushed function: AddGroupButton
        function AddGroupButtonPushed(app, event)
            varsSelected = app.UnselectedCovariatesListBox.Value;
            if length(varsSelected)>1
                app.CovarSelectErrorLabel.Visible = true;
            else
                currMatchVec = app.getMatchVectorOnCovariateName(varsSelected{1});
                app.groupIdx = currMatchVec;
            end
            app.unselectedIdxs = app.unselectedIdxs & (~app.groupIdx);
            app.updateListBoxes();
        end

        % Button pushed function: RemoveGroupButton
        function RemoveGroupButtonPushed(app, event)
            varsToRemove = app.GroupListBox.Value;
            for i = 1:length(varsToRemove)
                currMatchVec = app.getMatchVectorOnCovariateName(varsToRemove{i});
                app.groupIdx = app.groupIdx & (~currMatchVec);
                app.unselectedIdxs = app.unselectedIdxs | currMatchVec;
            end
            
            app.updateListBoxes();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SandwichEstimatorInputUIFigure = uifigure('Visible', 'off');
            app.SandwichEstimatorInputUIFigure.Name = 'Sandwich Estimator Input';
            app.LoadCovariatesButton = uibutton(app.SandwichEstimatorInputUIFigure, 'push');
            app.LoadCovariatesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCovariatesButtonPushed, true);
            app.LoadCovariatesButton.Text = 'Load Covariates';
            app.OKButton = uibutton(app.SandwichEstimatorInputUIFigure, 'push');
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.SandwichEstimatorInputUIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.CovariateFileNameLabel = uilabel(app.SandwichEstimatorInputUIFigure);
            app.CovariateFileNameLabel.Text = 'No Covariate Data';
            app.CovariateSetupPanel = uipanel(app.SandwichEstimatorInputUIFigure);
            app.CovariateSetupPanel.Title = 'Covariate Setup';
            app.AddNumericalButton = uibutton(app.CovariateSetupPanel, 'push');
            app.AddNumericalButton.ButtonPushedFcn = createCallbackFcn(app, @AddNumericalButtonPushed, true);
            app.AddNumericalButton.Text = '>> Add Numerical >>';
            app.RemoveNumericalButton = uibutton(app.CovariateSetupPanel, 'push');
            app.RemoveNumericalButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNumericalButtonPushed, true);
            app.RemoveNumericalButton.Text = '<< Remove Numerical <<';
            app.AddCategoricalButton = uibutton(app.CovariateSetupPanel, 'push');
            app.AddCategoricalButton.ButtonPushedFcn = createCallbackFcn(app, @AddCategoricalButtonPushed, true);
            app.AddCategoricalButton.Text = '>> Add Categorical >>';
            app.RemoveCategoricalButton = uibutton(app.CovariateSetupPanel, 'push');
            app.RemoveCategoricalButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCategoricalButtonPushed, true);
            app.RemoveCategoricalButton.Text = '<< Remove Categorical <<';
            app.AddGroupButton = uibutton(app.CovariateSetupPanel, 'push');
            app.AddGroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddGroupButtonPushed, true);
            app.AddGroupButton.Text = '>> Add Group >>';
            app.RemoveGroupButton = uibutton(app.CovariateSetupPanel, 'push');
            app.RemoveGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGroupButtonPushed, true);
            app.RemoveGroupButton.Text = '<< Remove Group <<';
            app.UnselectedCovariatesListBoxLabel = uilabel(app.CovariateSetupPanel);
            app.UnselectedCovariatesListBoxLabel.Text = 'Unselected Covariates';
            app.UnselectedCovariatesListBox = uilistbox(app.CovariateSetupPanel);
            app.UnselectedCovariatesListBox.Items = {'Covariates'};
            app.UnselectedCovariatesListBox.Multiselect = 'on';
            app.UnselectedCovariatesListBox.Value = {};
            app.NumericalListBoxLabel = uilabel(app.CovariateSetupPanel);
            app.NumericalListBoxLabel.Text = 'Numerical';
            app.NumericalListBox = uilistbox(app.CovariateSetupPanel);
            app.NumericalListBox.Items = {'Numerical Covariates'};
            app.NumericalListBox.Multiselect = 'on';
            app.NumericalListBox.Value = {};
            app.CategoricalListBoxLabel = uilabel(app.CovariateSetupPanel);
            app.CategoricalListBoxLabel.Text = 'Categorical';
            app.CategoricalListBox = uilistbox(app.CovariateSetupPanel);
            app.CategoricalListBox.Items = {'Cat Covariates'};
            app.CategoricalListBox.Multiselect = 'on';
            app.CategoricalListBox.Value = {};
            app.GroupListBoxLabel = uilabel(app.CovariateSetupPanel);
            app.GroupListBoxLabel.Text = 'Group';
            app.GroupListBox = uilistbox(app.CovariateSetupPanel);
            app.GroupListBox.Items = {'Group Var Name'};
            app.GroupListBox.Multiselect = 'on';
            app.GroupListBox.Value = {};
            app.CovarSelectErrorLabel = uilabel(app.CovariateSetupPanel);
            app.CovarSelectErrorLabel.Visible = 'off';
            app.CovarSelectErrorLabel.Text = 'ERROR: Must select single item for group, cannot select multiple';
            app.ContrastSetupPanel = uipanel(app.SandwichEstimatorInputUIFigure);
            app.ContrastSetupPanel.Title = 'Contrast Setup';
            app.UITable = uitable(app.ContrastSetupPanel);
            app.UITable.ColumnName = {'Var'; 'Contrast'};
            app.UITable.RowName = {};
            app.StandardErrorPanel = uipanel(app.SandwichEstimatorInputUIFigure);
            app.StandardErrorPanel.Title = 'Standard Error';
            app.StdErrorMethodDropDownLabel = uilabel(app.StandardErrorPanel);
            app.StdErrorMethodDropDownLabel.Text = 'Std Error Method';
            app.StdErrorMethodDropDown = uidropdown(app.StandardErrorPanel);
            app.StdErrorMethodDropDown.Items = {'Homoskedastic', 'Heteroskedastic', 'Unconstrained Blocks', 'Guillaume'};
            app.StdErrorMethodDropDown.Value = 'Homoskedastic';
            % Show the figure after all components are created
            app.SandwichEstimatorInputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SandwichEstimatorInput

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SandwichEstimatorInputUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SandwichEstimatorInputUIFigure)
        end
    end
end