classdef SandwichEstimatorInputGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SandwichEstimatorInputUIFigure  matlab.ui.Figure
        StandardErrorPanel              matlab.ui.container.Panel
        StdErrorMethodDropDown          matlab.ui.control.DropDown
        StdErrorMethodDropDownLabel     matlab.ui.control.Label
        ContrastSetupPanel              matlab.ui.container.Panel
        EditContrastButton              matlab.ui.control.Button
        RemoveContrastButton            matlab.ui.control.Button
        AddContrastButton               matlab.ui.control.Button
        ContrastsListBox                matlab.ui.control.ListBox
        ContrastsListBoxLabel           matlab.ui.control.Label
        ModelSetupPanel                 matlab.ui.container.Panel
        NuisanceGroupListBox            matlab.ui.control.ListBox
        NuisanceGroupListBoxLabel       matlab.ui.control.Label
        VariablesIncludedinModelListBox  matlab.ui.control.ListBox
        VariablesIncludedinModelListBoxLabel  matlab.ui.control.Label
        UnselectedVariablesListBox      matlab.ui.control.ListBox
        UnselectedVariablesListBoxLabel  matlab.ui.control.Label
        RemoveNuisanceGroupButton       matlab.ui.control.Button
        AddNuisanceGroupButton          matlab.ui.control.Button
        RemoveVariableButton            matlab.ui.control.Button
        AddVariableButton               matlab.ui.control.Button
        CovariateFileNameLabel          matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        allVariables
        dataAsTable
        unselectedVariables
        selectedVariables
        groupVariable
        stdObjType
        contrasts = {};
        contrastNames = {};
        
        unselectedIdxs
        selectedIdxs
        groupIdxs
        
        outputSettings
        dataFilename

        cursorObj
        
    end
    
    methods (Access = private)
        
        function initializeCovariateListBoxes(app)
            app.UnselectedVariablesListBox.Items = app.allVariables';
            app.VariablesIncludedinModelListBox.Items = {};
            app.NuisanceGroupListBox.Items = {};
        end
        function resetIdxsToAllUnselected(app)
            app.unselectedIdxs = true(1,length(app.allVariables));
            app.selectedIdxs = false(1,length(app.allVariables));
            app.groupIdxs = false(1,length(app.allVariables));            
        end
        
        function logicalMatchVector = getMatchVectorOnCovariateName(app, covarNameStr)
            
            logicalMatchVector = cellfun(@(x) strcmpi(x,covarNameStr), app.allVariables);
        
        end
        function updateSelectionListBoxes(app)
            app.UnselectedVariablesListBox.Items = app.allVariables(app.unselectedIdxs);
            app.VariablesIncludedinModelListBox.Items = app.allVariables(app.selectedIdxs);
            app.NuisanceGroupListBox.Items = app.allVariables(app.groupIdxs);
            
        end
        
        
        function clearConfirmed = confirmClearSavedContrasts(app)
            confirmAns = questdlg('Changing variables in model requires clearing contrasts. Ok?');
            if strcmpi(confirmAns, 'Yes')
                app.contrasts = {};
                app.contrastNames = {};
                app.updateContrastListBox();
                clearConfirmed = true;
            else
                clearConfirmed = false;
                return;
            end
        
        end
        
        function updateContrastListBox(app)
            app.ContrastsListBox.ItemsData = app.contrasts;
            app.ContrastsListBox.Items = app.contrastNames;
        end
        
        function setNuisanceGroupControlsEnableState(app, wantEnabled)
            if wantEnabled
                app.NuisanceGroupListBox.Enable = true;                
                app.AddNuisanceGroupButton.Enable = true;
                app.RemoveNuisanceGroupButton.Enable = true;
                
                app.NuisanceGroupListBox.Visible = true;                
                app.AddNuisanceGroupButton.Visible = true;
                app.RemoveNuisanceGroupButton.Visible = true;
                app.NuisanceGroupListBoxLabel.Visible = true;
            else
                app.NuisanceGroupListBox.Enable = false;
                app.AddNuisanceGroupButton.Enable = false;
                app.RemoveNuisanceGroupButton.Enable = false;
                
                app.NuisanceGroupListBox.Visible = false;
                app.AddNuisanceGroupButton.Visible = false;
                app.RemoveNuisanceGroupButton.Visible = false;
                app.NuisanceGroupListBoxLabel.Visible = false;
            end
        end        
        
        
        function combinedGroupId = labelUniqueCombosOfColumns(app, inMatrix)
            %This function is for creating a 'combined' group ID based off
            %of finding every unique combo of unique values in each column
            %
            %Ex: If input matrix is N x 3, for each of the 3 columns we'll
            %find all the unique values. We'll assign an arbitrary ID to
            %each unique combo, and return the N x 1 vector of IDs
            
            [numRows, numCols] = size(inMatrix);
            combinedGroupId = zeros(numRows,1);
                        
            unqCombos = unique(inMatrix,'rows');            
            numUnqGrps = size(unqCombos,1);
            
            for grpId = 1:numUnqGrps
                thisGrpValues = unqCombos(grpId,:);
                
                rowsThisGrp = ismember(inMatrix,thisGrpValues,'rows');
                
                combinedGroupId(rowsThisGrp) = grpId;
            end
        
        end
        
        function stateStruct = saveGUIStateToStruct(app)
            stateStruct = struct();
            stateStruct.dataAsTable = app.dataAsTable;
            stateStruct.selectedIdxs = app.selectedIdxs;
            stateStruct.unselectedIdxs = app.unselectedIdxs;
            stateStruct.groupIdxs = app.groupIdxs;
            stateStruct.dataFilename = app.dataFilename;
            
            stateStruct.contrasts = app.contrasts;
            stateStruct.contrastNames = app.contrastNames;
            
            stateStruct.stdObjName = app.StdErrorMethodDropDown.Value;
        end
        
        function loadGUIStateFromStruct(app, stateStruct)
            app.dataAsTable = stateStruct.dataAsTable;
            app.selectedIdxs = stateStruct.selectedIdxs;
            app.unselectedIdxs = stateStruct.unselectedIdxs;
            app.groupIdxs = stateStruct.groupIdxs;
            
            app.allVariables = app.dataAsTable.Properties.VariableNames;
            app.dataFilename = stateStruct.dataFilename;
            app.CovariateFileNameLabel.Text = app.dataFilename;
            
            app.contrasts = stateStruct.contrasts;
            app.contrastNames = stateStruct.contrastNames;
            
            try
                app.StdErrorMethodDropDown.Value = stateStruct.stdObjName;
            catch
                errordlg('Standard Error Object from saved state does not match list of built in options in GUI');
            end
            
            app.updateSelectionListBoxes();
            app.updateContrastListBox();
        
        end
        
        
        function changeCursorToBrain(app)
            
            
        end

        function changeCursorToDefault(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, outputObject)
            if nargin==1
                %no output object required, so just make one locally to
                %hold end output settings
                app.outputSettings = nla.edge.inputHelpers.SandwichEstimatorInput();
            
            else
                app.outputSettings = outputObject;
                
                if ~isempty(app.outputSettings.inputGuiSettingsStruct)
                    app.loadGUIStateFromStruct(app.outputSettings.inputGuiSettingsStruct);
                end
            end
            
            if app.outputSettings.FORCE_ORDINARY_LEAST_SQUARES
                stdErrorCalcName = 'Homoskedastic';
                app.StdErrorMethodDropDown.Enable = false;
                app.setNuisanceGroupControlsEnableState(false);
            else
                stdErrorCalcName = app.StdErrorMethodDropDown.Value;
                app.StdErrorMethodDropDown.Enable = true;
            end
            app.outputSettings.stdErrCalcObj = nla.helpers.stdError.(stdErrorCalcName);
            
            
            if strcmpi(app.StdErrorMethodDropDown.Value, 'Homoskedastic')
                app.setNuisanceGroupControlsEnableState(false);
            else
                app.setNuisanceGroupControlsEnableState(true);
            end

            app.cursorObj = nla.gfx.CursorControl(app.SandwichEstimatorInputUIFigure);
            
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            app.cursorObj.changeToThinking();

            [fName, pName] = uigetfile('*', 'Select Data File');
            if isempty(fName) || (~ischar(fName))
                %Cancelled. Return!
                app.cursorObj.changeToArrow();
                return;
            end

            try
                inDat = readtable(fullfile(pName,fName));
            catch exep
                errordlg(sprintf('Could not load data table from %s',fName));
                app.cursorObj.changeToArrow();
                return;
            end

            app.dataAsTable = inDat;
            
            app.allVariables = inDat.Properties.VariableNames;
            
            app.CovariateFileNameLabel.Text = fName;
            app.dataFilename = fName;
            app.initializeCovariateListBoxes();
            app.resetIdxsToAllUnselected();
            
            app.contrasts = {};
            app.contrastNames = {};
            app.updateContrastListBox();

            app.cursorObj.changeToArrow();
            
        end

        % Button pushed function: AddVariableButton
        function AddVariableButtonPushed(app, event)
            varsToAdd = app.UnselectedVariablesListBox.Value;
            if isempty(app.contrasts) || app.confirmClearSavedContrasts()
                %If there are contrasts, notify the user they will be
                %cleared if they change the variables in the model
                for i = 1:length(varsToAdd)
                    currMatchVec = app.getMatchVectorOnCovariateName(varsToAdd{i});
                    app.selectedIdxs = app.selectedIdxs | currMatchVec;
                end
                app.unselectedIdxs = app.unselectedIdxs & (~app.selectedIdxs);
                app.updateSelectionListBoxes();
            else
                return;
            end
            
        end

        % Button pushed function: RemoveVariableButton
        function RemoveVariableButtonPushed(app, event)
            varsToRemove = app.VariablesIncludedinModelListBox.Value;
            if isempty(app.contrasts) || app.confirmClearSavedContrasts()
                for i = 1:length(varsToRemove)
                    currMatchVec = app.getMatchVectorOnCovariateName(varsToRemove{i});
                    app.selectedIdxs = app.selectedIdxs & (~currMatchVec);
                    app.unselectedIdxs = app.unselectedIdxs | currMatchVec;
                end
                
                app.updateSelectionListBoxes();
            else
                return;
            end
        end

        % Button pushed function: AddNuisanceGroupButton
        function AddNuisanceGroupButtonPushed(app, event)
            varsSelected = app.UnselectedVariablesListBox.Value;
            for i = 1:length(varsSelected)
                currMatchVec = app.getMatchVectorOnCovariateName(varsSelected{i});
                app.groupIdxs = app.groupIdxs | currMatchVec;
            end
            app.unselectedIdxs = app.unselectedIdxs & (~app.groupIdxs);
            app.updateSelectionListBoxes();
            
        end

        % Button pushed function: RemoveNuisanceGroupButton
        function RemoveNuisanceGroupButtonPushed(app, event)
            varsToRemove = app.NuisanceGroupListBox.Value;
            for i = 1:length(varsToRemove)
                currMatchVec = app.getMatchVectorOnCovariateName(varsToRemove{i});
                app.groupIdxs = app.groupIdxs & (~currMatchVec);
                app.unselectedIdxs = app.unselectedIdxs | currMatchVec;
            end
            
            app.updateSelectionListBoxes();
        end

        % Button pushed function: AddContrastButton
        function AddContrastButtonPushed(app, event)
            newContrast = nla.edge.ContrastInput();
            newContrast.dataTable = app.dataAsTable(:, app.selectedIdxs);
            inputGUI = nla.edge.ContrastInputGUI(newContrast); %launch GUI for user to set fields of newContrast
            figure(inputGUI.NewContrastWizardUIFigure);
            uiwait(inputGUI.NewContrastWizardUIFigure);
            if newContrast.isValid()
                %check if new contrast name matches an existing one
                for i = 1:length(app.contrasts)
                    existingContrastName = app.contrasts{i}.name;
                    if strcmpi(existingContrastName, newContrast.name)
                        errordlg('New contrast cannot have same name as existing contrast!');
                        return;
                    end
                end
                app.contrasts{end+1} = newContrast;
                app.contrastNames{end+1} = newContrast.asDetailedString();
            end
            app.updateContrastListBox();
        end

        % Button pushed function: RemoveContrastButton
        function RemoveContrastButtonPushed(app, event)
            contrastToRemove = app.ContrastsListBox.Value;
            %determine index of contrast to remove
            idxToRemove = 0;
            for i = 1:length(app.contrasts)
                if strcmpi(contrastToRemove.name, app.contrasts{i}.name)
                    idxToRemove = i;
                    break;
                end
            end
            if idxToRemove>0
                app.contrasts(idxToRemove) = [];
                app.contrastNames(idxToRemove) = [];
            end
            app.updateContrastListBox();
        end

        % Button pushed function: EditContrastButton
        function EditContrastButtonPushed(app, event)
            contrastToEdit = app.ContrastsListBox.Value;
            %determine index of contrast to edit
            %(note: should be able to pass contrast directly to GUI for
            %modifying, since it inherits from handle, but using explicit
            %index for clarity)
            for i = 1:length(app.contrasts)
                if strcmpi(contrastToEdit.name, app.contrasts{i}.name)
                    idxToEdit = i;
                    break;
                end
            end
            if idxToEdit > 0
                contrastToEdit = app.contrasts{idxToEdit};
                inputGUI = nla.edge.ContrastInputGUI(contrastToEdit);
                uiwait(inputGUI.UIFigure);
                app.contrasts{idxToEdit} = contrastToEdit; %should be done by GUI since pass by reference, but calling anyway
                app.contrastNames{idxToEdit} = contrastToEdit.asDetailedString();
                app.updateContrastListBox();
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            %Return a struct with the following fields specific to Sandwich
            %Estimator
            % groupId (optional)
            % permute_method = nla.edge.permutationMethods.FCWildBootstrap();
            % stdErrCalcObj
            % behavior
            % covariates
            % contrasts
            
            guiStateStruct = app.saveGUIStateToStruct();
            app.outputSettings.inputGuiSettingsStruct = guiStateStruct;
            
            if any(app.groupIdxs)
                if length(app.groupIdxs)==1
                    app.outputSettings.groupId = table2array(app.dataAsTable(:,app.groupIdxs));
                else
                    %if multiple groups, we'll make a group ID based on
                    %each unique combo of values of all the columns
                    app.outputSettings.groupId = app.labelUniqueCombosOfColumns(app.dataAsTable(:,app.groupIdxs));
                end
            end
            
            stdErrCalcName = app.StdErrorMethodDropDown.Value;
            app.outputSettings.stdErrCalcObj = nla.helpers.stdError.(stdErrCalcName);
            
            app.outputSettings.covariates = table2array(app.dataAsTable(:,app.selectedIdxs));
            
            app.outputSettings.contrasts = [];
            app.outputSettings.contrastNames = app.contrastNames;
            for i = 1:length(app.contrasts)
                app.outputSettings.contrasts(i,:) = app.contrasts{i}.contrastVector;                
            end
            
            close(app.SandwichEstimatorInputUIFigure);            
        end

        % Value changed function: StdErrorMethodDropDown
        function StdErrorMethodDropDownValueChanged(app, event)
            value = app.StdErrorMethodDropDown.Value;
            app.outputSettings.stdErrCalcObj = nla.helpers.stdError.(value);
            
            %Enable/Disable nuisance group buttons depending on choice
            if strcmpi(value, 'Homoskedastic') || strcmpi(value, 'Heteroskedastic')
                app.setNuisanceGroupControlsEnableState(false);
            else
                app.setNuisanceGroupControlsEnableState(true);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.SandwichEstimatorInputGUI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SandwichEstimatorInputUIFigure = uifigure('Visible', 'off');
            app.SandwichEstimatorInputUIFigure.Name = 'Sandwich Estimator Input';
            app.LoadDataButton = uibutton(app.SandwichEstimatorInputUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.OKButton = uibutton(app.SandwichEstimatorInputUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.SandwichEstimatorInputUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CovariateFileNameLabel = uilabel(app.SandwichEstimatorInputUIFigure);
            app.CovariateFileNameLabel.Text = 'No Data';
            app.ModelSetupPanel = uipanel(app.SandwichEstimatorInputUIFigure);
            app.ModelSetupPanel.Title = 'Model Setup';
            app.AddVariableButton = uibutton(app.ModelSetupPanel, 'push');
            app.AddVariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddVariableButtonPushed, true);
            app.AddVariableButton.Text = '>> Add Variable >>';
            app.RemoveVariableButton = uibutton(app.ModelSetupPanel, 'push');
            app.RemoveVariableButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveVariableButtonPushed, true);
            app.RemoveVariableButton.Text = '<< Remove Variable <<';
            app.AddNuisanceGroupButton = uibutton(app.ModelSetupPanel, 'push');
            app.AddNuisanceGroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddNuisanceGroupButtonPushed, true);
            app.AddNuisanceGroupButton.Text = '>> Add Nuisance Group >>';
            app.RemoveNuisanceGroupButton = uibutton(app.ModelSetupPanel, 'push');
            app.RemoveNuisanceGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNuisanceGroupButtonPushed, true);
            app.RemoveNuisanceGroupButton.Text = '<< Remove Nuisance Group <<';
            app.UnselectedVariablesListBoxLabel = uilabel(app.ModelSetupPanel);
            app.UnselectedVariablesListBoxLabel.Text = 'Unselected Variables';
            app.UnselectedVariablesListBox = uilistbox(app.ModelSetupPanel);
            app.UnselectedVariablesListBox.Items = {};
            app.UnselectedVariablesListBox.Multiselect = 'on';
            app.UnselectedVariablesListBox.Value = {};
            app.VariablesIncludedinModelListBoxLabel = uilabel(app.ModelSetupPanel);
            app.VariablesIncludedinModelListBoxLabel.Text = 'Variables Included in Model';
            app.VariablesIncludedinModelListBox = uilistbox(app.ModelSetupPanel);
            app.VariablesIncludedinModelListBox.Items = {};
            app.VariablesIncludedinModelListBox.Multiselect = 'on';
            app.VariablesIncludedinModelListBox.Value = {};
            app.NuisanceGroupListBoxLabel = uilabel(app.ModelSetupPanel);
            app.NuisanceGroupListBoxLabel.Text = 'Nuisance Group';
            app.NuisanceGroupListBox = uilistbox(app.ModelSetupPanel);
            app.NuisanceGroupListBox.Items = {};
            app.NuisanceGroupListBox.Multiselect = 'on';
            app.NuisanceGroupListBox.Value = {};
            app.ContrastSetupPanel = uipanel(app.SandwichEstimatorInputUIFigure);
            app.ContrastSetupPanel.Title = 'Contrast Setup';
            app.ContrastsListBoxLabel = uilabel(app.ContrastSetupPanel);
            app.ContrastsListBoxLabel.Text = 'Contrasts';
            app.ContrastsListBox = uilistbox(app.ContrastSetupPanel);
            app.ContrastsListBox.Items = {};
            app.ContrastsListBox.Value = {};
            app.AddContrastButton = uibutton(app.ContrastSetupPanel, 'push');
            app.AddContrastButton.ButtonPushedFcn = createCallbackFcn(app, @AddContrastButtonPushed, true);
            app.AddContrastButton.Text = 'Add Contrast';
            app.RemoveContrastButton = uibutton(app.ContrastSetupPanel, 'push');
            app.RemoveContrastButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveContrastButtonPushed, true);
            app.RemoveContrastButton.Text = 'Remove Contrast';
            app.EditContrastButton = uibutton(app.ContrastSetupPanel, 'push');
            app.EditContrastButton.ButtonPushedFcn = createCallbackFcn(app, @EditContrastButtonPushed, true);
            app.EditContrastButton.Text = 'Edit Contrast';
            app.StandardErrorPanel = uipanel(app.SandwichEstimatorInputUIFigure);
            app.StandardErrorPanel.Title = 'Standard Error';
            app.StdErrorMethodDropDownLabel = uilabel(app.StandardErrorPanel);
            app.StdErrorMethodDropDownLabel.Text = 'Std Error Method';
            app.StdErrorMethodDropDown = uidropdown(app.StandardErrorPanel);
            app.StdErrorMethodDropDown.Items = {'Homoskedastic', 'Heteroskedastic', 'UnconstrainedBlocks', 'Guillaume'};
            app.StdErrorMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @StdErrorMethodDropDownValueChanged, true);
            app.StdErrorMethodDropDown.Value = 'Homoskedastic';
            % Show the figure after all components are created
            app.SandwichEstimatorInputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SandwichEstimatorInputGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SandwichEstimatorInputUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SandwichEstimatorInputUIFigure)
        end
    end
end