classdef BrainPlotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
    end

    
    properties
        plot_figure % Description
        edge_test_options % Description
        network_test_options % Description
        edge_test_result % Description
        network1 % Description
        network2 % Description
        test_name % Description
        upper_limit % Description
        lower_limit % Description
        color_map = cat(1, winter(1000), flip(autumn(1000))) % Description
        color_map_axis % Description
        color_bar % Description
        ROI_values = [] % Description
        function_connectivity_values = [] % Description
        ROI_radius % Description
        surface_parcels % Description
        mesh_type % Description
        mesh_alpha % Description
        all_edges = [] % Description
    end
    
    properties (Constant)
        noncorrelation_input_tests = ["chi_squared", "hypergeometric"] % These are tests that do not use correlation coefficients as inputs
        default_settings = struct("upper_limit", 0.5, "lower_limit", -0.5) 
    end
    
    properties (Dependent)
       is_noncorrelation_input
       function_connectivity_exists
       color_function_connectivity
    end
    
    methods (Access = private)
        
        
        function setROIandConnectivity(app)
            network1_ROI_indexes = app.edge_test_options.net_atlas.nets(app.network1).indexes;
            network2_ROI_indexes = app.edge_test_options.net_atlas.nets(app.network2).indexes;
            
            for ROI_index1_iterator = 1:numel(network1_ROI_indexes)
                network1_ROI_index = network1_ROI_indexes(ROI_index1_iterator);
                [coefficient1, coefficient2, function_connectivity1, function_connectivity2] = app.getCoefficients(network1_ROI_index, network2_ROI_indexes);
                app.ROI_values(network1_ROI_index) = (sum(coefficient1) + sum(coefficient2)) / (numel(coefficient1) + numel(coefficient2));
                app.function_connectivity_values(network1_ROI_index) = (sum(function_connectivity1) + sum(function_connectivity2)) / (numel(function_connectivity1) + numel(function_connectivity2));
            end
            
            for ROI_index2_iterator = 1:numel(network2_ROI_indexes)
                network2_ROI_index = network2_ROI_indexes(ROI_index2_iterator);
                [coefficient1, coefficient2, function_connectivity1, function_connectivity2] = app.getCoefficients(network2_ROI_index, network1_ROI_indexes);
                ROI_value = (sum(coefficient1) + sum(coefficient2)) / (numel(coefficient1) + numel(coefficient2));
                function_connectivity_value = (sum(function_connectivity1) + sum(function_connectivity2)) / (numel(function_connectivity1) + numel(function_connectivity2));
                if app.network1 == app.network2
                    app.ROI_values(network2_ROI_index) = (app.ROI_values(network2_ROI_index) + ROI_value) ./ 2;
                    app.function_connectivity_values(network2_ROI_index) = (app.function_connectivity_values(network2_ROI_index) + function_connectivity_value) ./ 2;
                else
                    app.ROI_values(network2_ROI_index) = ROI_value;
                    app.function_connectivity_values(network2_ROI_index) = function_connectivity_value;
                end
            end
        end
        
        function [coefficient1, coefficient2, function_connectivity1, function_connectivity2] = getCoefficients(app, network1_index, network2_indexes)
            coefficient1 = app.edge_test_result.coeff.get(network1_index, network2_indexes);
            coefficient2 = app.edge_test_result.coeff.get(network2_indexes, network1_index);

            function_connectivity1 = false;
            function_connectivity2 = false;
            if app.function_connectivity_exists
                function_connectivity1 = mean(app.edge_test_options.func_conn.get(network1_index, network2_indexes), 2);
                function_connectivity2 = mean(app.edge_test_options.func_conn.get(network2_indexes, network1_index), 2);
            end

            if app.is_noncorrelation_input
                probability_significance1 = app.edge_test_result.prob_sig.get(network1_index, network2_indexes);
                probability_significance2 = app.edge_test_result.prob_sig.get(network2_indexes, network1_index);

                coefficient1 = coefficient1(logical(probability_significance1));
                coefficient2 = coefficient2(logical(probability_significance2));
                function_connectivity1 = function_connectivity1(logical(probability_significance1));
                function_connectivity2 = function_connectivity2(logical(probability_significance2));
            end
        end
        
        function colors = mapColorsToLimits(app, value, function_connectivity_average, varargin)
            import nla.gfx.valToColor

            if isempty(varargin)
                scale_min = -0.5;
                scale_max = 0.5;
            else
                scale_min = str2double(varargin{1});
                scale_max = str2double(varargin{2});
            end

            color_rows = size(app.color_map);
            color_map_positive = app.color_map(1:(color_rows/2), :);
            color_map_negative = app.color_map(((color_rows/2) + 1):end, :);

            if app.color_function_connectivity
                colors_positive = valToColor(function_connectivity_average, scale_min, scale_max, color_map_positive);
                colors_negative = valToColor(function_connectivity_average, scale_min, scale_max, color_map_negative);
                colors(value > 0, :) = colors_positive(value > 0, :);
                colors(value <= 0, :) = colors_negative(value <= 0, :);
            else
                colors = valToColor(value, app.lower_limit, app.upper_limit, app.color_map);
            end
            colors(isnan(value), :) = 0.5;
        end
        
        
        function edges = drawEdges(app, ROI_position, plot_axis)

            point1_indexes = app.edge_test_options.net_atlas.nets(app.network1).indexes;
            point2_indexes = app.edge_test_options.net_atlas.nets(app.network2).indexes;

            edges = [];
            for point1_index = 1:numel(point1_indexes)
                point1 = point1_indexes(point1_index);
                for point2_index = 1:numel(point2_indexes)
                    point2 = point2_indexes(point2_index);
                    if point1 < point2
                        network_point1 = point2;
                        network_point2 = point1;
                    else
                        network_point1 = point1;
                        network_point2 = point2;
                    end
                    
                    [coefficient, ~, function_connectivity_vector, ~] = app.getCoefficients(network_point1, network_point2);
                    function_connectivity_average = mean(function_connectivity_vector);
                
                    if ~isempty(coefficient)
                        edge = app.assignColorToEdge(ROI_position, network_point1, network_point2, plot_axis, coefficient, function_connectivity_average);
                        edge.Annotation.LegendInformation.IconDisplayStyle = "off";
                        edges = [edges, edge];
                    end
                end
            end
        end
        
        function edge = assignColorToEdge(app, ROI_position, network_point1, network_point2, plot_axis, coefficient, function_connectivity_average, varargin)
            if ~isempty(coefficient)
                if ~isempty(varargin)
                    color_value = app.mapColorsToLimits(coefficient, function_connectivity_average, varargin{1}, varargin{2});
                else
                    color_value = app.mapColorsToLimits(coefficient, function_connectivity_average);
                end
                color_value = [reshape(color_value, [1, 3]), 0.5];
                edge = plot3(plot_axis, [ROI_position(network_point1, 1), ROI_position(network_point2, 1)],...
                    [ROI_position(network_point1, 2), ROI_position(network_point2, 2)],...
                    [ROI_position(network_point1, 3), ROI_position(network_point2, 3)],...
                    "Color", color_value, "LineWidth", 5);
                % Matlab says you can save a structure to the "UserData" field of a line. You cannot. so, we do something dumb
                edge_data = {};
                edge_data{1} = {"coefficient", "function_connectivity_average"};
                edge_data{2} = {coefficient, function_connectivity_average};
                set(edge, "UserData", edge_data)
            end
        end
        
        function drawCortex(app, anatomy, plot_axis, view_position, left_color, right_color)
            import nla.gfx.ViewPos

            % Set some defaults up
            plot_axis.Color = 'w';
            if ~exist("left_color", "var")
                left_color = repmat(0.5, [size(anatomy.hemi_l.nodes, 1), 3]);
            end
            if ~exist("right_color", "var")
                right_color = repmat(0.5, [size(anatomy.hemi_r.nodes, 1), 3]);
            end

            % Re-position hemisphere meshes to transverse/axial orientation
            [left_mesh, right_mesh] = nla.gfx.anatToMesh(anatomy, app.mesh_type, view_position);

            % Set lighting and view positioning
            if view_position == ViewPos.LAT || view_position == ViewPos.MED
                view(plot_axis, [-90, 0]);
                % local light is akin to a lightbulb at that location in space
                % infinite light has light that originates at that point and only goes in one direction
                light(plot_axis, "Position", [-100, 200, 0], "Style", "local");
                light(plot_axis, "Position", [-50, -500, 100], "Style", "infinite");
                light(plot_axis, "Position", [-50, 0, 0], "Style", "infinite");
            else
                view(plot_axis, [0, 0]);
                switch view_position                      
                    case ViewPos.DORSAL
                        view(plot_axis, [0, 90]);
                        light(plot_axis, "Position", [100, 300, 100], "Style", "infinite");
                    case ViewPos.LEFT
                        view(plot_axis, [-90, 0]);
                        light(plot_axis, "Position", [-100, 0, 0], "Style", "infinite");
                    case ViewPos.RIGHT
                        view(plot_axis, [90, 0]);
                        light(plot_axis, "Position", [100, 0, 0], "Style", "infinite");
                    case ViewPos.FRONT
                        view(plot_axis, [180, 0]);
                        light(plot_axis, "Position", [100, 300, 100], "Style", "infinite");
                    case ViewPos.BACK
                        light(plot_axis, "Position", [0, -200, 0], "Style", "infinite");
                end
                
                light(plot_axis, "Position", [-500, -20, 0], "Style", "local");
                light(plot_axis, "Position", [500, -20, 0], "Style", "local");
                light(plot_axis, "Position", [0, -200, 50], "Style", "local");
            end
            left_hemisphere = app.drawCortexHemisphere(plot_axis, anatomy.hemi_l, left_mesh, left_color);
            right_hemisphere = app.drawCortexHemisphere(plot_axis, anatomy.hemi_r, right_mesh, right_color);

            hold(plot_axis, "on");
            axis(plot_axis, "image");
            axis(plot_axis, "off");
        end
        
        
        function hemisphere = drawCortexHemisphere(app, plot_axis, hemisphere_anatomy, mesh, color)
            hemisphere = patch(plot_axis, "Faces", hemisphere_anatomy.elements(:, 1:3), "Vertices", mesh,...
                "EdgeColor", "none", "FaceColor", "interp", "FaceVertexCData", color,...
                "FaceLightin", "gouraud", "FaceAlpha", app.mesh_alpha, "AmbientStrength", 0.25,...
                "DiffuseStrength", 0.75, "SpecularStrength", 0.1);
            hemisphere.Annotation.LegendInformation.IconDisplayStyle = "off";
        end
        
        function edges = singlePlot(app, plot_axis, view_position, color_mode, color_matrix, upper_limit, lower_limit)

            if exist("upper_limit", "var")
                app.upper_limit = upper_limit;
            end
            if exist("lower_limit", "var")
                app.lower_limit = lower_limit;
            end

            connectivity_map = ~isnan(app.ROI_values);

            edges = [];
            if color_mode == nla.gfx.BrainColorMode.NONE
                [ROI_final_positions, ~] = app.getROIPositions(view_position, color_mode, color_matrix);
                app.drawCortex(app.edge_test_options.net_atlas.anat, plot_axis, view_position);
                edges = [edges, app.drawEdges(ROI_final_positions, plot_axis)];
            else
                app.mesh_alpha = 1;
                [ROI_final_positions, ROI_colors] = app.getROIPositions(view_position, nla.gfx.BrainColorMode.COLOR_ROIS);
                if ~isequal(color_mode, nla.gfx.BrainColorMode.NONE) && app.surface_parcels && ~islogical(app.edge_test_options.net_atlas.parcels) && isequal(size(app.edge_test_options.net_atlas.parcels.ctx_l,1), size(app.edge_test_options.net_atlas.anat.hemi_l.nodes, 1)) && isequal(size(app.edge_test_options.net_atlas.parcels.ctx_r, 1), size(app.edge_test_options.net_atlas.anat.hemi_r.nodes, 1))
                    ROI_color_map = [0.5 0.5 0.5; ROI_colors];
                    app.drawCortex(app.edge_test_options.net_atlas.anat, plot_axis, view_position, ROI_color_map(app.edge_test_options.net_atlas.parcels.ctx_l + 1, :), ROI_color_map(app.edge_test_options.net_atlas.parcels.ctx_r + 1, :));
                else
                app.drawCortex(ax, net_atlas.anat, ctx, app.mesh_alpha, view_pos);
                    if color_mode ~= BrainColorMode.NONE
                        for i = 1:net_atlas.numROIs()
                            % render a sphere at each ROI location
                            nla.gfx.drawSphere(ax, ROI_final_positions(i, :), ROI_colors(i, :), app.ROI_radius);
                        end
                    end
                end
            end
           
            if (~isfield(app.edge_test_options, "show_ROI_centroids")) || (isfield(app.edge_test_options, "show_ROI_centroids") && isequal(app.edge_test_options.show_ROI_centroids, true))
                app.drawROISpheres(ROI_final_positions, plot_axis, connectivity_map);
            end

            colorbar(plot_axis, "off");
            hold(plot_axis, "on");
        end
        
        function drawROISpheres(app, ROI_position, plot_axis, connectivity_map)
            for network = [app.network1, app.network2]
                network_indexes = app.edge_test_options.net_atlas.nets(network).indexes;
                for index_iterator = 1:numel(network_indexes)
                    index = network_indexes(index_iterator);
                    
                    if connectivity_map(index)
                        nla.gfx.drawSphere(plot_axis, ROI_position(index, :), app.edge_test_options.net_atlas.nets(network).color, app.ROI_radius);
                    end
                end
            end
        end
        
        function [ROI_final_positions, ROI_colors] = getROIPositions(app, view_position, color_mode, color_matrix)
            import nla.gfx.BrainColorMode

            [left_mesh, right_mesh] = nla.gfx.anatToMesh(app.edge_test_options.net_atlas.anat, app.mesh_type, view_position);
            ROI_positions = [app.edge_test_options.net_atlas.ROIs.pos]';

            [left_indexes, left_distances] = knnsearch(app.edge_test_options.net_atlas.anat.hemi_l.nodes, ROI_positions);
            [right_indexes, right_distances] = knnsearch(app.edge_test_options.net_atlas.anat.hemi_r.nodes, ROI_positions);

            for network = 1:app.edge_test_options.net_atlas.numNets()
                for network_indexes = 1:numel(app.edge_test_options.net_atlas.nets(network).indexes)
                    ROI_index = app.edge_test_options.net_atlas.nets(network).indexes(network_indexes);
                    offset = [NaN NaN NaN];
                    if left_distances(ROI_index) < right_distances(ROI_index)
                        offset = left_mesh(left_indexes(ROI_index), :) - app.edge_test_options.net_atlas.anat.hemi_l.nodes(left_indexes(ROI_index), :);
                    else
                        offset = right_mesh(right_indexes(ROI_index), :) - app.edge_test_options.net_atlas.anat.hemi_r.nodes(right_indexes(ROI_index), :);
                    end
                    ROI_final_positions(ROI_index, :) = ROI_positions(ROI_index, :) + offset;

                    switch color_mode
                        case BrainColorMode.DEFAULT_NETS
                            ROI_colors(ROI_index, :) = app.edge_test_options.net_atlas.nets(network).color;
                        case BrainColorMode.COLOR_NETS
                            ROI_colors(ROI_index, :) = color_matrix(network, :);
                        case BrainColorMode.COLOR_ROIS
                            ROI_colors(ROI_index, :) = color_matrix(ROI_index, :);
                        otherwise
                            ROI_colors = false;
                    end
                end
            end
        end
        
        function drawColorMap(app, plot_axis)
            if app.color_function_connectivity
                colormap(plot_axis, app.color_map);
                app.color_bar = colorbar(plot_axis);
                app.color_bar.Location = "southoutside";
                set(app.color_bar, 'ButtonDownFcn', @app.openModal);
            else
                colormap(plot_axis, app.color_map);
                app.color_bar = colorbar(plot_axis);
                app.color_bar.Location = "southoutside";
                set(app.color_bar, 'ButtonDownFcn', @app.openModal);
                
                number_of_ticks = 10;
                ticks = 0:number_of_ticks;
                app.color_bar.Ticks = double(ticks) ./ number_of_ticks;
                tick_labels = cell(number_of_ticks + 1, 1);
                for tick = ticks
                    tick_labels{tick + 1} = sprintf("%.2g", app.lower_limit + (tick * ((double(app.upper_limit - app.lower_limit) / number_of_ticks))));
                end
                app.color_bar.TickLabels = tick_labels;
                caxis(plot_axis, [0, 1]);
            end
        end
        
        function addTitle(app)
            figure_title = sprintf("Brain Visualization: Average of edge-level correlations between nets in [%s - %s] Network Pair", app.edge_test_options.net_atlas.nets(app.network1).name, app.edge_test_options.net_atlas.nets(app.network2).name);
            if app.is_noncorrelation_input
                figure_title = [figure_title sprintf("  (Edge-level P < %.2g)", app.edge_test_options.prob_max)];
            end
            app.plot_figure.Name = figure_title;
        end
        
        function applyScale(app, ~, ~, upper_value, lower_value)
            colorbar(app.color_bar, "off");
            app.upper_limit = str2double(upper_value.String);
            app.lower_limit = str2double(lower_value.String);
            for edge = app.all_edges
                edge_data = edge.UserData;
                edge_data_struct = struct();
                edge_data_names = edge_data{1};
                edge_data_values = edge_data{2};
                for idx = 1:numel(edge_data_names)
                    edge_data_struct.(edge_data_names{idx}) = edge_data_values{idx};
                end
                color_value = app.mapColorsToLimits(edge_data_struct.coefficient, edge_data_struct.function_connectivity_average, app.lower_limit, app.upper_limit);
                color_value = [reshape(color_value, [1, 3]), 0.5];
                set(edge, "Color", color_value);
            end
            app.drawColorMap(app.color_map_axis);
        end  

        function setDefaults(app, ~, ~, upper_limit_box, lower_limit_box)
            set(upper_limit_box, "String", app.default_settings.upper_limit);
            set(lower_limit_box, "String", app.default_settings.lower_limit);
        end
    end
    
    methods (Access = public)
        
        function drawBrainPlots(app)
            import nla.gfx.ViewPos nla.gfx.BrainColorMode
            
            app.setROIandConnectivity();
            
            if app.surface_parcels && ~islogical(app.edge_test_options.net_atlas.parcels)
                edges1 = app.singlePlot(subplot("Position", [0.45, 0.505, 0.53, 0.45], "Parent", app.Panel), ViewPos.LAT, BrainColorMode.COLOR_ROIS, app.color_map);
                edges2 = app.singlePlot(subplot("Position", [0.45, 0.055, 0.53, 0.45], "Parent", app.Panel), ViewPos.MED, BrainColorMode.COLOR_ROIS, app.color_map);
                app.all_edges = [edges1 edges2];
            else
                edges1 = app.singlePlot(subplot("Position", [0.45, 0.505, 0.26, 0.45], "Parent", app.Panel), ViewPos.BACK, BrainColorMode.NONE, app.color_map);
                edges2 = app.singlePlot(subplot("Position", [0.73, 0.505, 0.26, 0.45], "Parent", app.Panel), ViewPos.FRONT, BrainColorMode.NONE, app.color_map);
                edges3 = app.singlePlot(subplot("Position", [0.45, 0.055, 0.26, 0.45], "Parent", app.Panel), ViewPos.LEFT, BrainColorMode.NONE, app.color_map);
                edges4 = app.singlePlot(subplot("Position", [0.73, 0.055, 0.26, 0.45], "Parent", app.Panel), ViewPos.RIGHT, BrainColorMode.NONE, app.color_map);
                app.all_edges = [edges1 edges2 edges3 edges4];
            end
            
            if app.color_function_connectivity
                plot_axis = subplot("Position", [0.075, 0.175, 0.35, 0.75], "Parent", app.Panel);
            else
                plot_axis = subplot("Position", [0.075, 0.025, 0.35, 0.85], "Parent", app.Panel);
            end
            
            if app.surface_parcels && ~islogical(app.edge_test_options.net_atlas.parcels)
                edges5 = app.singlePlot(plot_axis, ViewPos.DORSAL, BrainColorMode.COLOR_ROIS, app.color_map);
            else
                edges5 = app.singlePlot(plot_axis, ViewPos.DORSAL, BrainColorMode.NONE, app.color_map);
            end
            app.all_edges = [app.all_edges edges5];
            
            light("Position", [0, 100, 100], "Style", "local");
            
            % Display Legend
            hold(plot_axis, "on");
            if app.network1 == app.network2
                legend_entry = bar(plot_axis, NaN);
                legend_entry.FaceColor = app.edge_test_options.net_atlas.nets(app.network1).color;
                legend_entry.DisplayName = app.edge_test_options.net_atlas.nets(app.network1).name;
            else
                for network = [app.network1, app.network2]
                    legend_entry = bar(plot_axis, NaN);
                    legend_entry.FaceColor = app.edge_test_options.net_atlas.nets(network).color;
                    legend_entry.DisplayName = app.edge_test_options.net_atlas.nets(network).name;
                end
            end
            hold(plot_axis, "off");
            nla.gfx.hideAxes(plot_axis);

            app.drawColorMap(plot_axis);

            app.color_map_axis = plot_axis;

            app.addTitle();
        end
    end
       
    methods
        %% 
        % GETTERS for dependent properties
        function value = get.is_noncorrelation_input(app)
            % Convenience method to determine if inputs were correlation coefficients, or "significance" values
            value = any(strcmp(app.noncorrelation_input_tests, app.test_name));
        end

        function value = get.function_connectivity_exists(app)
            value = isfield(app.edge_test_options, "func_conn");
        end

        function value = get.color_function_connectivity(app)
            value = false;
        end
        %%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, edge_test_result, edge_test_options, network_test_options, network1, network2)
            app.edge_test_result = edge_test_result;
            app.edge_test_options = edge_test_options;
            app.network_test_options = network_test_options;
            app.network1 = network1;
            app.network2 = network2;
            
            app.upper_limit = app.edge_test_result.coeff_range(2);
            app.lower_limit = app.edge_test_result.coeff_range(1);
            app.ROI_values = nan(app.edge_test_options.net_atlas.numROIs(), 1);
            app.function_connectivity_values = nan(app.edge_test_options.net_atlas.numROIs(), 1);
                        
            app.surface_parcels = true;
            app.mesh_type = nla.gfx.MeshType.STD;
            app.mesh_alpha = 0.25;
            app.ROI_radius = 3;
            
            app.drawBrainPlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainPlotApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end