classdef NLAResult < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveButton                      matlab.ui.container.Menu
        ResultTree                      matlab.ui.container.Tree
        FlipNestingButton               matlab.ui.control.Button
        EdgeLevelLabel                  matlab.ui.control.Label
        ViewEdgeLevelButton             matlab.ui.control.Button
        NetLevelLabel                   matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        DisplaySelectedButton           matlab.ui.control.Button
        netlevelpvalueplottingDropDownLabel  matlab.ui.control.Label
        netlevelpvalueplottingDropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        input_struct
        net_input_struct
        test_pool = false
        edge_result = false
        results = false
        nesting_by_method = true
        prog_bar = false
        cur_iter = 0
    end
    
    methods (Access = private)
        function node = createNode(app, parent, text, node_data)
            import nla.* % required due to matlab package system quirks
            node = uitreenode(parent);
            node.Text = text;
            if exist('node_data', 'var')
                node.NodeData = node_data;
            end
        end
        
        function setNesting(app, nesting_by_method)
            import nla.* % required due to matlab package system quirks
            % clear old nodes
            for i = 1:size(app.ResultTree.Children, 1)
                for j = 1:size(app.ResultTree.Children(1).Children, 1)
                    delete(app.ResultTree.Children(1).Children(1));
                end
                delete(app.ResultTree.Children(1))
            end
            
            % add new nodes
            if nesting_by_method
                if app.net_input_struct.nonpermuted
                    root = app.createNode(app.ResultTree, 'Non-permuted');
                    for i = 1:size(app.results.net_results, 2)
                        result = app.results.net_results{i};
                        if result.has_nonpermuted
                            flags = struct();
                            flags.show_nonpermuted = true;
                            app.createNode(root, result.name, {result, flags});
                        end
                    end
                end
                
                if app.net_input_struct.full_conn
                    root = app.createNode(app.ResultTree, 'Full connectome');
                    for i = 1:size(app.results.perm_net_results, 2)
                        result = app.results.perm_net_results{i};
                        if result.has_full_conn
                            flags = struct();
                            flags.show_full_conn = true;
                            app.createNode(root, result.name, {result, flags});
                        end
                    end
                end
                
                if app.net_input_struct.within_net_pair
                    root = app.createNode(app.ResultTree, 'Within Net-pair');
                    for i = 1:size(app.results.perm_net_results, 2)
                        result = app.results.perm_net_results{i};
                        if result.has_within_net_pair
                            flags = struct();
                            flags.show_within_net_pair = true;
                            app.createNode(root, result.name, {result, flags});
                        end
                    end
                end
            else
                for i = 1:size(app.results.net_results, 2)
                    root = app.createNode(app.ResultTree, app.results.net_results{i}.name);
                    
                    result = app.results.net_results{i};
                    if app.net_input_struct.nonpermuted && result.has_nonpermuted
                        flags = struct();
                        flags.show_nonpermuted = true;
                        app.createNode(root, 'Non-permuted', {result, flags});
                    end
                    
                    if app.net_input_struct.full_conn && result.has_full_conn
                        perm_result = app.results.perm_net_results{i};
                        if app.net_input_struct.full_conn && result.has_full_conn
                            flags = struct();
                            flags.show_full_conn = true;
                            app.createNode(root, 'Full connectome', {perm_result, flags});
                        end
                        if app.net_input_struct.within_net_pair && result.has_within_net_pair
                            flags = struct();
                            flags.show_within_net_pair = true;
                            app.createNode(root, 'Within Net-pair', {perm_result, flags});
                        end
                    end
                end
            end
        end
        
        function updateProgPermStats(app, ~)
            if ~islogical(app.prog_bar)
                app.cur_iter = app.cur_iter + 1;
                app.prog_bar.Message = sprintf('Running net-level statistics (%d/%d permutations)', app.cur_iter, app.net_input_struct.perm_count);
                app.prog_bar.Value = app.cur_iter ./ app.net_input_struct.perm_count;
                if app.prog_bar.CancelRequested
                    pool = gcp('nocreate');
                    delete(pool);
                    close(app.prog_bar);
                end
            end
        end
        
        function initFromInputs(app, test_pool, input_struct, net_input_struct)
            app.ViewEdgeLevelButton.Enable = false;
            app.RunButton.Enable = false;
            if numel(test_pool.net_tests) == 0
                app.RunButton.Visible = false;
            end
            app.SaveButton.Enable = false;
            
            enableNetButtons(app, false);
            
            app.EdgeLevelLabel.Text = test_pool.edge_test.name;
            
            prog = uiprogressdlg(app.UIFigure, 'Title', 'Running edge-level statistic', 'Message', sprintf('Running %s (non-permuted)', test_pool.edge_test.name), 'Indeterminate', true);
            drawnow;
            
            app.edge_result = test_pool.runEdgeTest(input_struct, false);
            
            app.input_struct = input_struct;
            app.net_input_struct = net_input_struct;
            app.test_pool = test_pool;

            app.ViewEdgeLevelButton.Enable = true;
            app.SaveButton.Enable = true;
            app.RunButton.Enable = true;
        
            close(prog);
        end
        
        function initFromResult(app, result, file_name)
            app.UIFigure.Name = sprintf('%s - NLA Result', file_name);
            
            app.EdgeLevelLabel.Text = result.edge_result.name;
            
            app.input_struct = result.input_struct;
            app.net_input_struct = result.net_input_struct;
            app.edge_result = result.edge_result;
            app.results = result;
            
            app.ViewEdgeLevelButton.Enable = true;
            app.SaveButton.Enable = true;
            app.RunButton.Enable = false;
            app.RunButton.Visible = false;
            
            enableNetButtons(app, ~islogical(result.net_results));
            
            drawnow();
            
            if ~islogical(result.net_results)
                app.setNesting(true);
            else
                app.results = false;
            end
        end
        
        function enableNetButtons(app, val)
            app.ResultTree.Enable = val;
            app.FlipNestingButton.Enable = val;
            app.DisplaySelectedButton.Enable = val;
            app.netlevelpvalueplottingDropDown.Enable = val;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test_pool, input_struct, net_input_struct)
            import nla.* % required due to matlab package system quirks
            
            app.UIFigure.Name = 'NLA Result';
            app.UIFigure.Icon = [findRootPath() 'thumb.png'];
            
            if isa(test_pool, 'nla.ResultPool')
                initFromResult(app, test_pool, input_struct);
            else
                initFromInputs(app, test_pool, input_struct, net_input_struct);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            import nla.* % required due to matlab package system quirks
            prog = uiprogressdlg(app.UIFigure, 'Title', 'Running statistics', 'Message', 'Running net-level statistics', 'Cancelable', 'on');
            prog.Value = 0.02;
            drawnow;
            
            net_results = app.test_pool.runNetTests(app.net_input_struct, app.edge_result, app.input_struct.net_atlas, false);
            
            if app.net_input_struct.full_conn
                prog.Message = 'Starting parallel pool...';
                prog.Value = 0.05;
                
                gcp;
                
                prog.Message = sprintf('Running net-level statistics (0/%d permutations)', app.net_input_struct.perm_count);
                prog.Value = 0;
                
                % Set handle reference
                app.prog_bar = prog;
                
                app.cur_iter = 0;
                
                % Data queue to output iteration # from parallel pool
                data_queue = parallel.pool.DataQueue;
                afterEach(data_queue, @app.updateProgPermStats);

                % Run permuted statistics
                app.test_pool.data_queue = data_queue;
                app.results = app.test_pool.runPerm(app.input_struct, app.net_input_struct, app.input_struct.net_atlas, app.edge_result, net_results, app.net_input_struct.perm_count);
                
                % Delete the data queue
                delete(data_queue);
                
                % Unset handle reference
                app.prog_bar = false;
            else
                app.results = ResultPool(app.input_struct, app.net_input_struct, app.input_struct.net_atlas, app.edge_result, net_results, false, false, false);
            end
            
            prog.Value = 0.98;
            
            app.RunButton.Enable = false;
            app.RunButton.Visible = false;
            
            enableNetButtons(app, true);
            
            drawnow();
            
            app.setNesting(true);
            
            close(prog);
        end

        % Menu selected function: SaveButton
        function SaveButtonPushed(app, event)
            import nla.* % required due to matlab package system quirks
            if islogical(app.results)
                % save just edge-level results
                result = ResultPool(app.input_struct, app.net_input_struct, app.input_struct.net_atlas, app.edge_result, false, false, false, false);
            else
                result = app.results;
            end
            
            [file, path] = uiputfile({'*.mat', 'Result (*.mat)'}, 'Save Result File', 'result.mat');
            if file ~= 0
                prog = uiprogressdlg(app.UIFigure, 'Title', 'Saving results', 'Message', sprintf('Saving to %s', file), 'Indeterminate', true);
                drawnow;
                
                result.to_file([path file]);
                
                app.UIFigure.Name = sprintf('%s - NLA Result', file);
                
                close(prog);
            end
        end

        % Button pushed function: FlipNestingButton
        function FlipNestingButtonPushed(app, event)
            import nla.* % required due to matlab package system quirks
            app.nesting_by_method = ~app.nesting_by_method;
            app.setNesting(app.nesting_by_method)
        end

        % Button pushed function: ViewEdgeLevelButton
        function ViewEdgeLevelButtonPushed(app, event)
            import nla.* % required due to matlab package system quirks
            prog = uiprogressdlg(app.UIFigure, 'Title', 'Generating figures', 'Message', sprintf('Generating %s figures', app.edge_result.name), 'Indeterminate', true);
            drawnow;
            
            flags = struct();
            flags.display_sig = true;
            
            app.edge_result.output(app.input_struct.net_atlas, flags)
            
            close(prog);
        end

        % Button pushed function: DisplaySelectedButton
        function DisplaySelectedButtonPushed(app, event)
            prog = uiprogressdlg(app.UIFigure, 'Title', 'Generating figures', 'Message', 'Generating net-level figures');
            prog.Value = 0.02;
            drawnow;
            
            selected_nodes = app.ResultTree.SelectedNodes;
            for i = 1:size(selected_nodes, 1)
                if ~isempty(selected_nodes(i).NodeData)
                    result = selected_nodes(i).NodeData{1};
                    flags = selected_nodes(i).NodeData{2};
                    
                    prog.Message = sprintf('Generating %s figures', result.name);
                    
                    result.output(app.net_input_struct, app.input_struct.net_atlas, flags);
                    
                    prog.Value = i / size(selected_nodes, 1);
                end
            end
            
            close(prog);
        end

        % Value changed function: netlevelpvalueplottingDropDown
        function PValModeDropDownValueChanged(app, event)
            value = app.netlevelpvalueplottingDropDown.Value;
            if strcmp(value, 'linear')
                % Plot p-values on linear scale
                app.net_input_struct.log_plot_prob = false;
            else
                % Plot p-values on logarithmic scale
                app.net_input_struct.log_plot_prob = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveButton = uimenu(app.FileMenu);
            app.SaveButton.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Accelerator = 's';
            app.SaveButton.Text = 'Save';
            app.ResultTree = uitree(app.UIFigure);
            app.ResultTree.Multiselect = 'on';
            app.FlipNestingButton = uibutton(app.UIFigure, 'push');
            app.FlipNestingButton.ButtonPushedFcn = createCallbackFcn(app, @FlipNestingButtonPushed, true);
            app.FlipNestingButton.Text = 'Flip nesting';
            app.EdgeLevelLabel = uilabel(app.UIFigure);
            app.EdgeLevelLabel.Text = 'Edge-level statistic';
            app.ViewEdgeLevelButton = uibutton(app.UIFigure, 'push');
            app.ViewEdgeLevelButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEdgeLevelButtonPushed, true);
            app.ViewEdgeLevelButton.Text = 'View';
            app.NetLevelLabel = uilabel(app.UIFigure);
            app.NetLevelLabel.Text = 'Network-level statistics';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DisplaySelectedButton = uibutton(app.UIFigure, 'push');
            app.DisplaySelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySelectedButtonPushed, true);
            app.DisplaySelectedButton.Text = 'View selected';
            app.netlevelpvalueplottingDropDownLabel = uilabel(app.UIFigure);
            app.netlevelpvalueplottingDropDownLabel.Text = 'net-level p-value plotting:';
            app.netlevelpvalueplottingDropDown = uidropdown(app.UIFigure);
            app.netlevelpvalueplottingDropDown.Items = {'linear', 'log'};
            app.netlevelpvalueplottingDropDown.ValueChangedFcn = createCallbackFcn(app, @PValModeDropDownValueChanged, true);
            app.netlevelpvalueplottingDropDown.Value = 'linear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLAResult(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end