classdef NLA_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkLevelAnalysisUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenpreviousresultMenu        matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        Panel_7                       matlab.ui.container.Panel
        RunQualityControlButton       matlab.ui.control.Button
        MethodsPanel                  matlab.ui.container.Panel
        NonPermutedCheckBox           matlab.ui.control.CheckBox
        FullConnCheckBox              matlab.ui.control.CheckBox
        WithinNetPairCheckBox         matlab.ui.control.CheckBox
        PermutationcountEditField_2Label  matlab.ui.control.Label
        PermutationcountEditField     matlab.ui.control.NumericEditField
        Panel_3                       matlab.ui.container.Panel
        NoneButton                    matlab.ui.control.Button
        NetTestSelector               matlab.ui.control.ListBox
        NetworkleveltestsCtrlclickformultipleLabel  matlab.ui.control.Label
        EdgeInputsPanel               matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        EdgeTestSelector              matlab.ui.control.DropDown
        EdgeleveltestDropDownLabel    matlab.ui.control.Label
        BranchLabel                   matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        RunButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        test_pool = nla.TestPool();
        input_struct = struct();
        input_fields = {};
        net_input_struct
        net_input_fields = {};
        stored_edge_result = false;
    end
    
    methods (Access = private)
        function runWithInputs(app, mode)
            import nla.*
            % check if all input fields are satisfied
            [error_str, satisfied] = validateInputStruct(app.input_fields, 'Must satisfy fields:', true);
            [error_str, satisfied] = validateInputStruct(app.net_input_fields, error_str, satisfied);

            %If user has selected tests that don't support within net-pair,
            %and is not running QC, show warning and confirm continue
            show_within_NP_warning = app.checkForTestsThatDontSupportWithinNetPair(app.test_pool.net_tests);
            is_running_qc = (mode == 1);
            if show_within_NP_warning && ~is_running_qc
                warning_msg = ['Chi Squared and Hypergeometric Tests don''t support within net pair tests. ',...
                                'Within net pair results for those tests will not be generated. Continue?'];
                quest_ans = questdlg(warning_msg);
                if ~strcmpi(quest_ans,'Yes')
                    %If user does not want to continue, stop
                    return;
                end
            end
            
            if satisfied
                error_str = "";
                errors_found = false;
                
                % store input fields
                for i = 1:numel(app.input_fields)
                    [app.input_struct, error] = app.input_fields{i}.store(app.input_struct);
                    if ~islogical(error)
                        error_str = [error_str sprintf('\n - %s: %s', app.input_fields{i}.disp_name, error)];
                        errors_found = true;
                    end
                end
                % store input fields
                % removing net input fields and using default values for
                % behavior_count and prob_max (ADE20260320)
                if numel(app.net_input_fields) == 0
                    app.net_input_struct.behavior_count = 1;
                    app.net_input_struct.prob_max = 0.05;
                else
                    %This block is if we need to re-add required inputs for
                    %net tests into the NLA GUI, and will only be hit if
                    %any selected net tests have required inputs
                
                    for i = 1:numel(app.net_input_fields)
                        [app.net_input_struct, error] = app.net_input_fields{i}.store(app.net_input_struct);
                        if ~islogical(error)
                            error_str = [error_str sprintf('\n - %s: %s', app.net_input_fields{i}.disp_name, error)];
                            errors_found = true;
                        end
                    end
                end
                % check permutation method
                if isa(app.test_pool.edge_test, 'nla.edge.test.SandwichEstimator')
                    %Leave the permute_method to what was selected before,
                    %which should be
                    %nla.edge.permuteMethods.FCWildBootstrap();
                elseif ~isfield(app.input_struct, "permutation_groups") || isfield(app.input_struct, "permutation_groups") && (isequal(app.input_struct.permutation_groups, false) || isempty(app.input_struct.permutation_groups))
                    app.input_struct.permute_method = nla.edge.permutationMethods.BehaviorVec();
                else
                    app.input_struct.permute_method = nla.edge.permutationMethods.MultiLevel();
                    app.input_struct.permute_method = app.input_struct.permute_method.createPermutationTree(app.input_struct);
                end
                
                if errors_found
                    uialert(app.NetworkLevelAnalysisUIFigure, error_str, 'Error with inputs');
                else
                    % Handle partial variance
                    if isfield(app.input_struct, 'partial_variance') && app.input_struct.partial_variance ~= PartialVarianceType.NONE && isfield(app.input_struct, 'covariates') && isfield(app.input_struct, 'func_conn') && isfield(app.input_struct, 'behavior')
                        app.input_struct.func_conn_unpartialed = app.input_struct.func_conn;
                        app.input_struct.behavior_unpartialed = app.input_struct.behavior;
                        [app.input_struct.func_conn, app.input_struct.behavior] = partialVariance(app.input_struct.func_conn, app.input_struct.behavior, app.input_struct.covariates, app.input_struct.partial_variance);
                    end
                    
                    % make sure to deep copy all handles, as memory is shared
                    % between windows
                    if mode == 0
                        NLAResult(copy(app.test_pool), app.input_struct, app.net_input_struct);
                    else
                        NLAQualityControl(copy(app.test_pool), app.input_struct, app.net_input_struct);
                    end
                end
            else
                % TODO ideally the run button would just stay greyed out until
                % all inputs were satisfied
                uialert(app.NetworkLevelAnalysisUIFigure, error_str, 'Inputs not satisfied');
            end
        end

        function result = checkForTestsThatDontSupportWithinNetPair(app, net_tests)
            result = false;
            for i = 1:length(net_tests)
                if net_tests{i}.allows_within_net_pair == false
                    result = true;
                    return;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import nla.* % required due to matlab package system quirks
            
            app.net_input_struct = net.genBaseInputs();
            app.net_input_struct.perm_count = 10000;
            app.input_struct.perm_count = 10000;
            
            root_path = findRootPath();
            
            app.NetworkLevelAnalysisUIFigure.Name = 'NLA';
            if ~ispc
                %Below line throws error on Windows, but fine on Linux/Max
                %Cause unknown, so live without icon on PC (ADE 20250617)
                app.NetworkLevelAnalysisUIFigure.Icon = [root_path 'thumb.png'];
            end
            
            app.BranchLabel.Text = sprintf('gui | %s', helpers.git.commitString());
            
            drawnow;
            
            % Edge-level tests
            edgeLevelOptions = genTests('edge.test');
            
            edgeLevelNames = string;
            for i = 1:numel(edgeLevelOptions)
                edgeLevelNames(i) = edgeLevelOptions{i}.name;
            end
            app.EdgeTestSelector.Items = edgeLevelNames;
            app.EdgeTestSelector.ItemsData = edgeLevelOptions;
            
            app.EdgeTestSelectorValueChanged(true);
            
            % Network-level tests
            netLevelOptions = genTests('net.test');
            netLevelNames = string;
            for i = 1:numel(netLevelOptions)
                netLevelNames(i) = netLevelOptions{i}.display_name;
            end
            app.NetTestSelector.Items = netLevelNames;
            app.NetTestSelector.ItemsData = netLevelOptions;
            
            app.NetTestSelectorValueChanged(true);
            
            pause(1); %Adding pause here fixes issue of edge input controls drawing too low on GUI startup. Better place for this?
            app.EdgeInputsPanel.AutoResizeChildren = 'off';
        end

        % Value changed function: EdgeTestSelector
        function EdgeTestSelectorValueChanged(app, event)
            import nla.* % required due to matlab package system quirks
            
            test = app.EdgeTestSelector.Value;
            
            app.test_pool.edge_test = test;
            
            app.EdgeInputsPanel.Title = strcat(test.name, ' inputs');
            
            % store old input fields and undraw
            for i = 1:numel(app.input_fields)
                app.input_struct = app.input_fields{i}.store(app.input_struct);
                app.input_fields{i}.undraw();
            end
            
            % required inputs to run this test
            inputs = test.requiredInputs();
            app.input_fields = inputField.reduce(inputs);
            
            % display input fields
            x = inputField.LABEL_GAP * 2;
            y = app.EdgeInputsPanel.InnerPosition(4);
            for i = 1:numel(app.input_fields)
                y = y - inputField.LABEL_GAP;
                [w, h] = app.input_fields{i}.draw(x, y, app.EdgeInputsPanel, app.NetworkLevelAnalysisUIFigure);
                app.input_fields{i}.read(app.input_struct);
                y = y - h;
            end
        end

        % Value changed function: NetTestSelector
        function NetTestSelectorValueChanged(app, event)
            import nla.*
            
            tests = app.NetTestSelector.Value;
            
            app.test_pool.net_tests = tests;
            
            % store old input fields and undraw
            for i = 1:numel(app.net_input_fields)
                app.net_input_struct = app.net_input_fields{i}.store(app.net_input_struct);
                app.net_input_fields{i}.undraw();
            end
            
            % required inputs to run these tests
            inputs = {};
            for i = 1:numel(tests)
                inputs = cat(2, inputs, tests{i}.requiredInputs());
            end
            app.net_input_fields = inputField.reduce(inputs);
            
            if numel(app.net_input_fields) > 0
                %Removed net inputs panel from GUI and just use default values
                %for pmax and test_count for net tests, so this block will
                %not be entered if there are no net test settings inputs.
                %
                %If need to re-implement net tests, re-add a panel called
                %'NetInputsPanel' back to GUI to make this block functional
                %again (ADE 20260320).
                
                x = inputField.LABEL_GAP * 2;
                y = app.NetInputsPanel.InnerPosition(4);
                for i = 1:numel(app.net_input_fields)
                    y = y - inputField.LABEL_GAP;
                    [w, h] = app.net_input_fields{i}.draw(x, y, app.NetInputsPanel, app.NetworkLevelAnalysisUIFigure);
                    app.net_input_fields{i}.read(app.net_input_struct);
                    y = y - h;
                end
            end
        end

        % Value changed function: FullConnCheckBox, 
        % NonPermutedCheckBox, WithinNetPairCheckBox
        function MethodButtonGroupSelectionChanged(app, event)
            if ~(app.net_input_struct.full_connectome == app.FullConnCheckBox.Value)
                app.WithinNetPairCheckBox.Value = false;
            elseif ~(app.net_input_struct.within_network_pair == app.WithinNetPairCheckBox.Value)
                app.FullConnCheckBox.Value = true;
            else
                return
            end
            
            app.net_input_struct.no_permutations = app.NonPermutedCheckBox.Value;
            app.net_input_struct.full_connectome = app.FullConnCheckBox.Value;
            app.net_input_struct.within_network_pair = app.WithinNetPairCheckBox.Value;
            
            % remove permutation count when nonpermuted selected
            if app.net_input_struct.full_connectome
                app.PermutationcountEditField.Enable = true;
                app.PermutationcountEditField.Value = app.net_input_struct.perm_count;
            else
                app.PermutationcountEditField.Enable = false;
                app.PermutationcountEditField.Value = 0;
            end
        end

        % Value changed function: PermutationcountEditField
        function PermutationcountEditFieldValueChanged(app, event)
            app.net_input_struct.perm_count = app.PermutationcountEditField.Value;
            app.input_struct.perm_count = app.PermutationcountEditField.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            runWithInputs(app, 0);
        end

        % Button pushed function: NoneButton
        function NoneButtonPushed(app, event)
            import nla.*
            
            tests = app.NetTestSelector.Value;
            app.test_pool.net_tests = tests;
            
            % store old input fields and undraw
            for i = 1:numel(app.net_input_fields)
                app.net_input_struct = app.net_input_fields{i}.store(app.net_input_struct);
                app.net_input_fields{i}.undraw();
            end
            
            app.NetTestSelector.Value = {};
            app.net_input_fields = {};
            app.test_pool.net_tests = {};
        end

        % Menu selected function: OpenpreviousresultMenu
        function OpenpreviousresultMenuSelected(app, event)
            [file, path, idx] = uigetfile({'*.mat', 'Results (*.mat)'}, 'Select Previous Result'); %TODO add all file options readtable can read from
            
            if idx ~= 0
                prog = uiprogressdlg(app.NetworkLevelAnalysisUIFigure, 'Title', 'Loading previous result', 'Message', sprintf('Loading %s', file), 'Indeterminate', true);
                drawnow;
                
                [results, old_data] = nla.net.result.NetworkTestResult().loadPreviousData([path file]);
                
                try
                    if isa(results, 'nla.ResultPool')
                        NLAResult(results, file, false, old_data);
                    end
                    close(prog);
                catch ex
                    close(prog);
                    uialert(app.NetworkLevelAnalysisUIFigure, ex.message, 'Error while loading previous result');
                end
                close(prog)
            end
        end

        % Button pushed function: RunQualityControlButton
        function RunQualityControlButtonPushed(app, event)
            import nla.*
            runWithInputs(app, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkLevelAnalysisUIFigure = uifigure('Visible', 'off');
            app.NetworkLevelAnalysisUIFigure.Name = 'NetworkLevelAnalysis';
            app.FileMenu = uimenu(app.NetworkLevelAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenpreviousresultMenu = uimenu(app.FileMenu);
            app.OpenpreviousresultMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenpreviousresultMenuSelected, true);
            app.OpenpreviousresultMenu.Accelerator = 'o';
            app.OpenpreviousresultMenu.Text = 'Open previous result';
            app.GridLayout = uigridlayout(app.NetworkLevelAnalysisUIFigure);
            app.Panel_7 = uipanel(app.GridLayout);
            app.RunQualityControlButton = uibutton(app.Panel_7, 'push');
            app.RunQualityControlButton.ButtonPushedFcn = createCallbackFcn(app, @RunQualityControlButtonPushed, true);
            app.RunQualityControlButton.Text = 'Run Quality Control';
            app.MethodsPanel = uipanel(app.Panel_7);
            app.MethodsPanel.Title = 'MethodsPanel';
            app.NonPermutedCheckBox = uicheckbox(app.MethodsPanel);
            app.NonPermutedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.NonPermutedCheckBox.Tag = 'nonpermuted';
            app.NonPermutedCheckBox.Enable = 'off';
            app.NonPermutedCheckBox.Text = 'Non-permuted';
            app.NonPermutedCheckBox.Value = true;
            app.FullConnCheckBox = uicheckbox(app.MethodsPanel);
            app.FullConnCheckBox.ValueChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.FullConnCheckBox.Tag = 'full_con';
            app.FullConnCheckBox.Text = 'Full connectome';
            app.FullConnCheckBox.Value = true;
            app.WithinNetPairCheckBox = uicheckbox(app.MethodsPanel);
            app.WithinNetPairCheckBox.ValueChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.WithinNetPairCheckBox.Tag = 'within_net_pair';
            app.WithinNetPairCheckBox.Text = 'Within Net-pair';
            app.WithinNetPairCheckBox.Value = true;
            app.PermutationcountEditField_2Label = uilabel(app.Panel_7);
            app.PermutationcountEditField_2Label.Text = 'Permutation count:';
            app.PermutationcountEditField = uieditfield(app.Panel_7, 'numeric');
            app.PermutationcountEditField.Limits = [0 Inf];
            app.PermutationcountEditField.RoundFractionalValues = 'on';
            app.PermutationcountEditField.ValueDisplayFormat = '%11d';
            app.PermutationcountEditField.ValueChangedFcn = createCallbackFcn(app, @PermutationcountEditFieldValueChanged, true);
            app.PermutationcountEditField.Value = 10000;
            app.Panel_3 = uipanel(app.GridLayout);
            app.NoneButton = uibutton(app.Panel_3, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @NoneButtonPushed, true);
            app.NoneButton.Text = 'None';
            app.NetTestSelector = uilistbox(app.Panel_3);
            app.NetTestSelector.Items = {};
            app.NetTestSelector.Multiselect = 'on';
            app.NetTestSelector.ValueChangedFcn = createCallbackFcn(app, @NetTestSelectorValueChanged, true);
            app.NetTestSelector.Value = {};
            app.NetworkleveltestsCtrlclickformultipleLabel = uilabel(app.Panel_3);
            app.NetworkleveltestsCtrlclickformultipleLabel.Text = {'Network-level tests'; '(Ctrl+click for multiple)'};
            app.EdgeInputsPanel = uipanel(app.GridLayout);
            app.EdgeInputsPanel.Title = 'Edge-level inputs';
            app.Panel = uipanel(app.GridLayout);
            app.EdgeTestSelector = uidropdown(app.Panel);
            app.EdgeTestSelector.Items = {};
            app.EdgeTestSelector.ValueChangedFcn = createCallbackFcn(app, @EdgeTestSelectorValueChanged, true);
            app.EdgeTestSelector.Value = {};
            app.EdgeleveltestDropDownLabel = uilabel(app.Panel);
            app.EdgeleveltestDropDownLabel.Text = 'Edge-level test';
            app.BranchLabel = uilabel(app.GridLayout);
            app.BranchLabel.Text = 'gui | unknown_branch:0000000';
            app.Panel_5 = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.Panel_5, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.NetworkLevelAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLA_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkLevelAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkLevelAnalysisUIFigure)
        end
    end
end